classdef MRI_Simulation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel8        matlab.ui.container.Panel
        pickPhantom     matlab.ui.control.DropDown
        run             matlab.ui.control.Button
        pickTrajectory  matlab.ui.control.DropDown
        linesLabel      matlab.ui.control.Label
        text4           matlab.ui.control.Label
        lines           matlab.ui.control.DropDown
        points          matlab.ui.control.DropDown
        popupmenu6      matlab.ui.control.DropDown
        text6           matlab.ui.control.Label
        slider1         matlab.ui.control.Slider
        Mask            matlab.ui.container.Panel
        axes16          matlab.ui.control.UIAxes
        trajectoryAxes  matlab.ui.control.UIAxes
        difference      matlab.ui.container.Panel
        diffAxes        matlab.ui.control.UIAxes
        MRI             matlab.ui.container.Panel
        MRIAxes         matlab.ui.control.UIAxes
        phantom         matlab.ui.container.Panel
        phantomAxes     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function clearAxes(~, handles)
                axes(handles.phantomAxes); cla;
                axes(handles.MRIAxes); cla;
                axes(handles.diffAxes); cla;
        end
        
        function linesText_Callback(~, hObject, ~, handles)
            val = get(hObject, 'String');
            if isempty(str2double(val))
                set(hObject, 'String', handles.trajInfo.num_lines);
            else
                handles.trajInfo.num_lines = str2double(val);
                guidata(hObject, handles);
            end
        end
        
        function linesText_CreateFcn(~, hObject, ~, ~)
            if ismac && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pointsText_Callback(~, hObject, ~, handles)
            val = get(hObject, 'String');
            if isempty(str2double(val))
                set(hObject, 'String', handles.trajInfo.num_points_per_line);
            else
                handles.trajInfo.num_points_per_line = str2double(val);
                guidata(hObject, handles);
            end
        end
        
        function pointsText_CreateFcn(~, hObject, ~, ~)
            if ismac && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MRI_Simulation_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);
            
            handles.output = hObject;
            guidata(hObject, handles);
            set(handles.lines, 'Value', 3);
            set(handles.points, 'Value', 3);
            guidata(hObject, handles);
        end

        % Value changed function: pickPhantom
        function pickPhantom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            opt = get(handles.pickPhantom, 'Value');
            axes(handles.phantomAxes);
            switch opt
                case 1
                    clearAxes(app, handles)
                case 2
                    imshow('../data/phantom_type1.png');
                case 3
                    imshow('../data/phantom_type2.png');
                case 4
                    imshow('../data/phantom_type3.png');
                case 5
                    [file, path, ~] = uigetfile({'*.png;*.jpg;*.jpeg;*.JPG;*.JPEG'});
                    if ischar(file) && ischar(path)
                        imdata = imread(strcat(path, file));
                        if size(imdata, 3) == 3
                            imdata = rgb2gray(imdata);
                        end
                        imdata = imresize(imdata, [3840,3840]);
                        imshow(imdata);
                    else
                        clearAxes(app, handles)
                    end
            end
        end

        % Value changed function: pickTrajectory
        function pickTrajectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            opt = get(handles.pickTrajectory, 'Value');
            axes(handles.trajectoryAxes);
            switch opt
                case 1
                    cla;
                case 2
                    imshow('../data/cartesian.png');
                case 3
                    imshow('../data/radial.png');
            end
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            trajectory = get(handles.pickTrajectory, 'Value');
            
            if(isempty(get(handles.phantomAxes, 'Children')))
                return;
            end
            
            lines = 2^(get(handles.lines, 'Value') + 3);
            points = 2^(get(handles.points, 'Value') + 3);
            maskPercent = get(handles.slider1, 'Value');
            maskType = get(handles.popupmenu6, 'Value');
            
            
            switch trajectory
                case 1
                    return;
                case 2
                    [MRI, mask] = MRI_Cartesian(getimage(handles.phantomAxes), lines, points, maskType - 2, maskPercent);
                    axes(handles.axes16);
                    imshow(mask);
                case 3
                    [MRI, mask] = MRI_Radial(getimage(handles.phantomAxes), lines, points, maskType - 2, maskPercent);
                    axes(handles.axes16);
                    imshow(mask);
            
            end
            
            axes(handles.MRIAxes);
            imshow(MRI, [0, max(MRI(:))]);
            
            compareImg = abs(double(getimage(handles.phantomAxes)) - double(MRI));
            axes(handles.diffAxes);
            imshow(compareImg, [0 max(compareImg(:))]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MRI_Acquisition_Simulator';
            app.figure1.Tag = 'figure1';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Options';
            app.uipanel8.Tag = 'uipanel8';
            app.pickPhantom = uidropdown(app.uipanel8);
            app.pickPhantom.Items = {'Pick a phantom', 'Phantom 1', 'Phantom 2', 'Phantom 3', 'Custom Image'};
            app.pickPhantom.ValueChangedFcn = createCallbackFcn(app, @pickPhantom_Callback, true);
            app.pickPhantom.Tag = 'pickPhantom';
            app.pickPhantom.Value = 'Pick a phantom';
            app.run = uibutton(app.uipanel8, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'Run Simulation';
            app.pickTrajectory = uidropdown(app.uipanel8);
            app.pickTrajectory.Items = {'Pick a trajectory', 'Cartesian', 'Radial'};
            app.pickTrajectory.ValueChangedFcn = createCallbackFcn(app, @pickTrajectory_Callback, true);
            app.pickTrajectory.Tag = 'pickTrajectory';
            app.pickTrajectory.Value = 'Pick a trajectory';
            app.linesLabel = uilabel(app.uipanel8);
            app.linesLabel.Tag = 'linesLabel';
            app.linesLabel.Text = 'Number of lines';
            app.text4 = uilabel(app.uipanel8);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Number of points';
            app.lines = uidropdown(app.uipanel8);
            app.lines.Items = {'16', '32', '64', '128', '256'};
            app.lines.Tag = 'lines';
            app.lines.Value = '16';
            app.points = uidropdown(app.uipanel8);
            app.points.Items = {'16', '32', '64', '128', '256'};
            app.points.Tag = 'points';
            app.points.Value = '16';
            app.popupmenu6 = uidropdown(app.uipanel8);
            app.popupmenu6.Items = {'Pick a mask type', 'none', 'square', 'circle'};
            app.popupmenu6.Tag = 'popupmenu6';
            app.popupmenu6.Value = 'Pick a mask type';
            app.text6 = uilabel(app.uipanel8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Mask Percent';
            app.slider1 = uislider(app.uipanel8);
            app.slider1.Limits = [0.1 1];
            app.slider1.MajorTicks = [];
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider1.Value = 1;
            app.Mask = uipanel(app.uipanel8);
            app.Mask.Title = 'Mask';
            app.Mask.Tag = 'Mask';
            app.axes16 = uiaxes(app.Mask);
            app.axes16.Tag = 'axes16';
            app.trajectoryAxes = uiaxes(app.uipanel8);
            app.trajectoryAxes.Tag = 'trajectoryAxes';
            app.difference = uipanel(app.figure1);
            app.difference.Title = 'Difference Between Images';
            app.difference.Tag = 'difference';
            app.diffAxes = uiaxes(app.difference);
            app.diffAxes.Tag = 'diffAxes';
            app.MRI = uipanel(app.figure1);
            app.MRI.Title = 'Acquisited MRI Image';
            app.MRI.Tag = 'MRI';
            app.MRIAxes = uiaxes(app.MRI);
            app.MRIAxes.Tag = 'MRIAxes';
            app.phantom = uipanel(app.figure1);
            app.phantom.Title = 'Selected Phantom';
            app.phantom.Tag = 'phantom';
            app.phantomAxes = uiaxes(app.phantom);
            app.phantomAxes.Tag = 'phantomAxes';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRI_Simulation_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MRI_Simulation_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end